classdef Interfaz_prev_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        EjecutarButton                  matlab.ui.control.Button
        tabla1                          matlab.ui.control.Table
        AnimarButton                    matlab.ui.control.Button
        CambiarMovimientoCheckBox       matlab.ui.control.CheckBox
        ActivarComunicacion             matlab.ui.control.Button
        SeleccioneMetodoButtonGroup     matlab.ui.container.ButtonGroup
        GeometricaButton                matlab.ui.control.RadioButton
        DenavitButton                   matlab.ui.control.RadioButton
        a180Label                       matlab.ui.control.Label
        a0Label                         matlab.ui.control.Label
        a90Label                        matlab.ui.control.Label
        a90Label_2                      matlab.ui.control.Label
        ComunicacionLamp                matlab.ui.control.Lamp
        Q1E                             matlab.ui.control.NumericEditField
        Q1EditFieldLabel                matlab.ui.control.Label
        Q2EditFieldLabel                matlab.ui.control.Label
        Q2E                             matlab.ui.control.NumericEditField
        Q3EditFieldLabel                matlab.ui.control.Label
        Q3E                             matlab.ui.control.NumericEditField
        Q4EditFieldLabel                matlab.ui.control.Label
        Q4E                             matlab.ui.control.NumericEditField
        SERIALEditFieldLabel            matlab.ui.control.Label
        SERIALEditField                 matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        CinematicaDirectaLabel          matlab.ui.control.Label
        CinematicaInversaLabel          matlab.ui.control.Label
        ValoresArticularesLabel         matlab.ui.control.Label
        SeleccioneMetodoInvButtonGroup  matlab.ui.container.ButtonGroup
        GeometricaInvButton             matlab.ui.control.RadioButton
        AnaliticaButton                 matlab.ui.control.RadioButton
        EjecutarButton_2                matlab.ui.control.Button
        MTHTotalLabel                   matlab.ui.control.Label
        TablaInver                      matlab.ui.control.Table
        SolucionesdelaCinematicaInversaLabel  matlab.ui.control.Label
        TablaValInver                   matlab.ui.control.Table
        ValoresDeLaInversaLabel         matlab.ui.control.Label
        EFCEditFieldLabel               matlab.ui.control.Label
        EFCE                            matlab.ui.control.EditField
        SolucionOptimaLabel             matlab.ui.control.Label
        TablaInverSolOptima             matlab.ui.control.Table
        AR4DFLabel                      matlab.ui.control.Label
        axes                            matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    arduino;  % Objeto serial para comunicarse con el Arduino
    isRunning = false;  % Bandera para indicar si la comunicación está activa
    Banderita
    qq_actual = [0, 0, 0, 0];  % Posición actual del robot (ángulos en grados)
    NOAP_original = eye(4);  % Nueva variable para guardar NOAP original
end
    
methods (Access = private)
        
    function dh=denavit(app,theta, d, a, alfa)
        dh=[cos(theta) -cos(alfa)*sin(theta) sin(alfa)*sin(theta) a*cos(theta);
            sin(theta) cos(alfa)*cos(theta) -sin(alfa)*cos(theta) a*sin(theta);
            0          sin(alfa)             cos(alfa)                       d;
            0          0                     0                              1];
    end
    
  
    % Función mejorada dibujarsistema5
    function dibujarsistema5(app, Mth, scale, systemType)
        % systemType: 0=base, 1=intermedio, 2=efector final
        
        % Configurar propiedades según el tipo de sistema
        if systemType == 0 % Base
            axis_width = 3;
            text_offset = 1.3;
            font_size = 7;
            text_weight = 'bold';
        elseif systemType == 2 % Efector final
            axis_width = 3;
            text_offset = 1.3;
            font_size = 7;
            text_weight = 'bold';
        else % Sistemas intermedios
            axis_width = 3;
            text_offset = 1.3;
            font_size = 7;
            text_weight = 'normal';
        end
        
        % Extraer componentes de los ejes
        origen = Mth(1:3,4)';
        eje_x = Mth(1:3,1)';
        eje_y = Mth(1:3,2)';
        eje_z = Mth(1:3,3)';
        view(app.axes, 3);
        % Dibujar ejes
        line(app.axes, [origen(1), origen(1)+eje_x(1)*scale], ...
                      [origen(2), origen(2)+eje_x(2)*scale], ...
                      [origen(3), origen(3)+eje_x(3)*scale], ...
                      'Color', 'r', 'LineWidth', axis_width);
        
        line(app.axes, [origen(1), origen(1)+eje_y(1)*scale], ...
                      [origen(2), origen(2)+eje_y(2)*scale], ...
                      [origen(3), origen(3)+eje_y(3)*scale], ...
                      'Color', 'g', 'LineWidth', axis_width);
        
        line(app.axes, [origen(1), origen(1)+eje_z(1)*scale], ...
                      [origen(2), origen(2)+eje_z(2)*scale], ...
                      [origen(3), origen(3)+eje_z(3)*scale], ...
                      'Color', 'b', 'LineWidth', axis_width);
        
        % Añadir etiquetas solo para base y efector final
        if systemType == 0 || systemType == 2
            text(app.axes, origen(1)+eje_x(1)*scale*text_offset, ...
                          origen(2)+eje_x(2)*scale*text_offset, ...
                          origen(3)+eje_x(3)*scale*text_offset, ...
                          'X', 'Color', 'r', 'FontWeight', text_weight, 'FontSize', font_size);
            
            text(app.axes, origen(1)+eje_y(1)*scale*text_offset, ...
                          origen(2)+eje_y(2)*scale*text_offset, ...
                          origen(3)+eje_y(3)*scale*text_offset, ...
                          'Y', 'Color', 'g', 'FontWeight', text_weight, 'FontSize', font_size);
            
            text(app.axes, origen(1)+eje_z(1)*scale*text_offset, ...
                          origen(2)+eje_z(2)*scale*text_offset, ...
                          origen(3)+eje_z(3)*scale*text_offset, ...
                          'Z', 'Color', 'b', 'FontWeight', text_weight, 'FontSize', font_size);
        end
    end
    
    function M4=DirectaDH(app,q1,q2,q3,q4)
        q1=deg2rad(q1);
        q2=deg2rad(q2);
        q3=deg2rad(q3);
        q4=deg2rad(q4);
        
        l1 = 7.6;
        l2 = 10.5;
        l3 = 10;
        l4 = 4;
        
        M0=eye(4);
        M1=app.denavit(q1, l1, 0, -pi/2);
        M2=app.denavit(q2, 0, l2, 0);
        M2=M1*M2;
        M3=app.denavit((q3-pi/2), 0, 0, -pi/2);
        M3=M2*M3;
        M4=app.denavit(q4, (l3+l4), 0, 0);
        M4=M3*M4;
        
        %app.tabla1.Data=M4;
        %app.TablaValInver.Data = M4;
        
        % Dibujar sistemas de referencia con la nueva función mejorada
        app.dibujarsistema5(M0, 3, 0);  % Base (tipo 0)
        app.dibujarsistema5(M1, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M2, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M3, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M4, 3, 2);  % Efector final (tipo 2)
        
        p0=[M0(1,4),M0(2,4),M0(3,4)];
        p1=[M1(1,4),M1(2,4),M1(3,4)];
        p2=[M2(1,4),M2(2,4),M2(3,4)];
        p3_visual = p2 + (M3(1:3, 3)' * l3);
        p4 = [M4(1,4), M4(2,4), M4(3,4)];
        
        % Dibujar eslabones usando el punto visual
        line(app.axes, [p0(1) p1(1)], [p0(2) p1(2)], [p0(3) p1(3)], 'LineWidth', 5, 'Color', 'g');
        line(app.axes, [p1(1) p2(1)], [p1(2) p2(2)], [p1(3) p2(3)], 'LineWidth', 5, 'Color', 'b');
        line(app.axes, [p2(1) p3_visual(1)], [p2(2) p3_visual(2)], [p2(3) p3_visual(3)], 'LineWidth', 5, 'Color', 'r');
        line(app.axes, [p3_visual(1) p4(1)], [p3_visual(2) p4(2)], [p3_visual(3) p4(3)], 'LineWidth', 5, 'Color', 'y');
        
        % Configuración de ejes (manteniendo tu configuración original)
        app.axes.Title.String = 'Manipulador de 4 GDL';
        app.axes.XLabel.String = 'X[cm]';
        app.axes.YLabel.String = 'Y[cm]';
        app.axes.ZLabel.String = 'Z[cm]';

        xticks = -50:10:50;
        app.axes.XLimMode = 'manual';
        app.axes.XLim = [-50 50];
        app.axes.XTickMode = 'manual';
        app.axes.XTick = xticks;
        app.axes.XTickLabelMode = 'manual';
        app.axes.XTickLabel = xticks;
   
        yticks = -50:10:50;
        app.axes.YLimMode = 'manual';
        app.axes.YLim = [-50 50];
        app.axes.YTickMode = 'manual';
        app.axes.YTick = yticks;
        app.axes.YTickLabelMode = 'manual';
        app.axes.YTickLabel = yticks;
 
        zticks = 0:10:50;
        app.axes.ZLimMode = 'manual';
        app.axes.ZLim = [0 50];
        app.axes.ZTickMode = 'manual';
        app.axes.ZTick = zticks;
        app.axes.ZTickLabelMode = 'manual';
        app.axes.ZTickLabel = zticks;
        grid(app.axes, 'on');
    end
    
    function Animar(app,q1,q2,q3,q4)
        global qq
        paso=20;
        q=[q1,q2,q3,q4];
        
        if isempty(qq)
            qq = [0, 0, 0, 0]; 
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x=app.DirectaDH(Qq(1),Qq(2),Qq(3),Qq(4));
            
            app.TablaValInver.Data =x;
            pause(0.1)
        end
        qq=Qq;
        app.qq_actual = Qq;
    end
    
    function AnimarPasoPaso(app,q1,q2,q3,q4)
        global qq
        paso=20;
        q=[q1,q2,q3,q4];
        
        if isempty(qq)
            qq = [0, 0, 0, 0]; 
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x=app.DirectaDH(Qq(1),qq(2),qq(3),qq(4));
            
            app.TablaValInver.Data=x;
            pause(0.1)
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x=app.DirectaDH(q1,Qq(2),qq(3),qq(4));

            app.TablaValInver.Data=x;
            pause(0.1)
        end
         
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x=app.DirectaDH(q1,q2,Qq(3),qq(4));

            app.TablaValInver.Data=x;
            pause(0.1)
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x=app.DirectaDH(q1,q2,q3,Qq(4));

            app.TablaValInver.Data=x;
            pause(0.1)
        end
        qq=Qq;
        app.qq_actual = Qq;
    end
    
    % MÉTODOS GEOMÉTRICOS (actualizados con la nueva visualización)
    function G = DirectaGeometrica(app, q1, q2, q3, q4)
        % Convertir ángulos a radianes
        theta = deg2rad([q1, q2, q3, q4]);
        
        % Calcular matriz homogénea con método geométrico
        G = app.Geometrica([q1, q2, q3, q4]);
        
        % Para la animación necesitamos las matrices intermedias
        L = [7.6, 10.5, 10, 4]; % l1, l2, l3+l4
        
        % Base (M0)
        M0 = eye(4);
        
        % Articulación 1 (solo rotación en Z)
        M1 = [cos(theta(1)) -sin(theta(1)) 0 0;
              sin(theta(1)) cos(theta(1)) 0 0;
              0 0 1 L(1);
              0 0 0 1];
        
        % Articulación 2
        r2 = L(2)*cos(theta(2));
        z2 = L(1) - L(2)*sin(theta(2));
        M2 = [cos(theta(1))*cos(theta(2)) -cos(theta(1))*sin(theta(2)) -sin(theta(1)) r2*cos(theta(1));
              sin(theta(1))*cos(theta(2)) -sin(theta(1))*sin(theta(2)) cos(theta(1)) r2*sin(theta(1));
              sin(theta(2)) cos(theta(2)) 0 z2;
              0 0 0 1];
        
        % Articulación 3 (punto intermedio)
        r3 = L(2)*cos(theta(2)) + L(3)*cos(theta(2)+theta(3));
        z3 = L(1) - L(2)*sin(theta(2)) - L(3)*sin(theta(2)+theta(3));
        M3 = [cos(theta(1))*cos(theta(2)+theta(3)) -cos(theta(1))*sin(theta(2)+theta(3)) -sin(theta(1)) r3*cos(theta(1));
              sin(theta(1))*cos(theta(2)+theta(3)) -sin(theta(1))*sin(theta(2)+theta(3)) cos(theta(1)) r3*sin(theta(1));
              sin(theta(2)+theta(3)) cos(theta(2)+theta(3)) 0 z3;
              0 0 0 1];
        
        % Efector final (ya calculado en G)
        M4 = G;
        
        % Dibujar sistemas de referencia con la nueva función mejorada
        app.dibujarsistema5(M0, 3, 0);  % Base (tipo 0)
        app.dibujarsistema5(M1, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M2, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M3, 3, 1);  % Intermedio (tipo 1)
        app.dibujarsistema5(M4, 3, 2);  % Efector final (tipo 2)
        
        % Dibujar eslabones (manteniendo tus colores originales)
        p0 = [M0(1,4), M0(2,4), M0(3,4)];
        p1 = [M1(1,4), M1(2,4), M1(3,4)];
        p2 = [M2(1,4), M2(2,4), M2(3,4)];
        p3 = [M3(1,4), M3(2,4), M3(3,4)];
        p4 = [M4(1,4), M4(2,4), M4(3,4)];
        
        line(app.axes, [p0(1) p1(1)], [p0(2) p1(2)], [p0(3) p1(3)], 'LineWidth', 5, 'Color', 'g');
        line(app.axes, [p1(1) p2(1)], [p1(2) p2(2)], [p1(3) p2(3)], 'LineWidth', 5, 'Color', 'b');
        line(app.axes, [p2(1) p3(1)], [p2(2) p3(2)], [p2(3) p3(3)], 'LineWidth', 5, 'Color', 'r');
        line(app.axes, [p3(1) p4(1)], [p3(2) p4(2)], [p3(3) p4(3)], 'LineWidth', 5, 'Color', 'y');
        
        app.tabla1.Data = G;
        app.TablaValInver.Data=G;
        app.axes.Title.String = 'Manipulador de 4 GDL';
        app.axes.XLabel.String = 'X[cm]';
        app.axes.YLabel.String = 'Y[cm]';
        app.axes.ZLabel.String = 'Z[cm]';

        xticks = -50:10:50;
        app.axes.XLimMode = 'manual';
        app.axes.XLim = [-50 50];
        app.axes.XTickMode = 'manual';
        app.axes.XTick = xticks;
        app.axes.XTickLabelMode = 'manual';
        app.axes.XTickLabel = xticks;
   
        yticks = -50:10:50;
        app.axes.YLimMode = 'manual';
        app.axes.YLim = [-50 50];
        app.axes.YTickMode = 'manual';
        app.axes.YTick = yticks;
        app.axes.YTickLabelMode = 'manual';
        app.axes.YTickLabel = yticks;
 
        zticks = 0:10:50;
        app.axes.ZLimMode = 'manual';
        app.axes.ZLim = [0 50];
        app.axes.ZTickMode = 'manual';
        app.axes.ZTick = zticks;
        app.axes.ZTickLabelMode = 'manual';
        app.axes.ZTickLabel = zticks;
        grid(app.axes, 'on');
    end
    
    function AnimarGeometrica(app, q1, q2, q3, q4)
        global qq_geo
        paso = 20;
        q = [q1, q2, q3, q4];
        
        if isempty(qq_geo)
            qq_geo = [0, 0, 0, 0]; 
        end
        
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(Qq(1), Qq(2), Qq(3), Qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data=x;
            pause(0.1);
        end
        qq_geo = Qq;
    end
    
    function AnimarPasoPasoGeometrica(app, q1, q2, q3, q4)
        global qq_geo
        paso = 20;
        q = [q1, q2, q3, q4];
        
        if isempty(qq_geo)
            qq_geo = [0, 0, 0, 0]; 
        end
        
        % Animación articulación 1
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(Qq(1), qq_geo(2), qq_geo(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data=x;
            pause(0.1);
        end
        
        % Animación articulación 2
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, Qq(2), qq_geo(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data=x;
            pause(0.1);
        end
         
        % Animación articulación 3
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, q2, Qq(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data=x;
            pause(0.1);
        end
        
        % Animación articulación 4
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, q2, q3, Qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data=x;
            pause(0.1);
        end
        qq_geo = Qq;
    end
    
        function AnimarConActualizacionTabla1(app, q1, q2, q3, q4)
        global qq
        paso = 20;
        q = [q1, q2, q3, q4];
        
        if isempty(qq)
            qq = [0, 0, 0, 0]; 
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x = app.DirectaDH(Qq(1), Qq(2), Qq(3), Qq(4));
            % Actualizar AMBAS tablas durante animación
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1)
        end
        qq = Qq;
        app.qq_actual = Qq;
    end
    
    function AnimarPasoPasoConActualizacionTabla1(app, q1, q2, q3, q4)
        global qq
        paso = 20;
        q = [q1, q2, q3, q4];
        
        if isempty(qq)
            qq = [0, 0, 0, 0]; 
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x = app.DirectaDH(Qq(1), qq(2), qq(3), qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1)
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x = app.DirectaDH(q1, Qq(2), qq(3), qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1)
        end
         
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x = app.DirectaDH(q1, q2, Qq(3), qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1)
        end
        
        for i = 0:paso
            Qq = qq + (q - qq) * i / paso;
            cla(app.axes);
            x = app.DirectaDH(q1, q2, q3, Qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1)
        end
        qq = Qq;
        app.qq_actual = Qq;
    end
    
    function AnimarGeometricaConActualizacionTabla1(app, q1, q2, q3, q4)
    global qq_geo
    paso = 20;
    q = [q1, q2, q3, q4];
    
    if isempty(qq_geo)
        qq_geo = [0, 0, 0, 0]; 
    end
    
    for i = 0:paso
        Qq = qq_geo + (q - qq_geo) * i / paso;
        cla(app.axes);
        x = app.DirectaGeometrica(Qq(1), Qq(2), Qq(3), Qq(4));
        % Actualizar AMBAS tablas
        app.tabla1.Data = x;
        app.TablaValInver.Data = x;
        pause(0.1);
    end
    qq_geo = Qq;
    app.qq_actual = Qq;
    end

    function AnimarPasoPasoGeometricaConActualizacionTabla1(app, q1, q2, q3, q4)
        global qq_geo
        paso = 20;
        q = [q1, q2, q3, q4];
        
        if isempty(qq_geo)
            qq_geo = [0, 0, 0, 0]; 
        end
        
        % Animación articulación 1
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(Qq(1), qq_geo(2), qq_geo(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1);
        end
        
        % Animación articulación 2
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, Qq(2), qq_geo(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1);
        end
         
        % Animación articulación 3
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, q2, Qq(3), qq_geo(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1);
        end
        
        % Animación articulación 4
        for i = 0:paso
            Qq = qq_geo + (q - qq_geo) * i / paso;
            cla(app.axes);
            x = app.DirectaGeometrica(q1, q2, q3, Qq(4));
            app.tabla1.Data = x;
            app.TablaValInver.Data = x;
            pause(0.1);
        end
        qq_geo = Qq;
        app.qq_actual = Qq;
    end
    
    function G = Geometrica(app,theta)
        L = [7.6, 10.5, 10, 4];
        t1 = deg2rad(theta(1));
        t2 = deg2rad(theta(2));
        t3 = deg2rad(theta(3));
        t4 = deg2rad(theta(4));
    
        Com = [0 0 1 0;
               0 -1 0 0;
               1 0 0 0;
               0 0 0 1];
        Noa = app.matriz_rotacion_compuesta(t1,t2,t3,t4);
    
        G = Noa * Com;

        % Calcular términos intermedios
        r = L(2)*cos(-t2) + (L(3)+L(4))*cos(-t2 - t3);
    
        % Calcular coordenadas de posición
        Px = r * cos(t1);
        Py = r * sin(t1);
        Pz = L(1) + (L(2)*sin(-t2)) + (L(3)+L(4))*sin((-t2-t3));
        nuez= Pz
    
        % Matriz homogénea de traslación
        A =[1 0 0 Px;
            0 1 0 Py;
            0 0 1 Pz;
            0 0 0 1];
        
        G = A * G;
    end
    
    function Noa = matriz_rotacion_compuesta(app,q1, q2, q3, q4)
        % Entradas: ángulos en radianes
        q23 = -1*(q2 + q3);
    
        Noa = [cos(q1)*cos(q23),  -cos(q1)*sin(q23)*sin(q4) - sin(q1)*cos(q4),  -cos(q1)*sin(q23)*cos(q4) + sin(q1)*sin(q4) 0;
            sin(q1)*cos(q23),  -sin(q1)*sin(q23)*sin(q4) + cos(q1)*cos(q4),  -sin(q1)*sin(q23)*cos(q4) - cos(q1)*sin(q4) 0;
            sin(q23),           cos(q23)*sin(q4),                             cos(q23)*cos(q4)                           0;
            0                     0                                               0                                      1];
    end
    
function Q = InversaGeo(app)
  
    
    T = app.TablaValInver.Data;
    app.TablaValInver.Data = T;
    if ~isequal(size(T), [4 4])
        uialert(app.UIFigure,'La tabla debe contener una MTH 4x4.','Error MTH');
        Q = [];
        return;
    end


    Px = T(1,4);
    Py = T(2,4);
    Pz = T(3,4);
    x4 = [T(1,1), T(2,1), T(3,1), 0];   % eje x deseado del efector

    l1 = 7.6;
    l2 = 10.5;
    l3 = 10;
    l4 = 4;
    
    
    q1 = atan2(Py,Px);
    
    if q1 < 0
       q1 = q1 + pi;
    end

    q3 = atan2((sqrt(1-((Py^2 + Px^2 + (Pz - l1)^2 - (l3 + l4)^2 - l2^2)/(2 * (l3 + l4) * l2 ))^2)),(((Py^2 + Px^2 + (Pz - l1)^2 - (l3 + l4)^2 - l2^2)/(2 * (l3 + l4) * l2))));
    q31 = q3;
    q32 = -1*q3;

    q21 = (-(atan((Pz-l1)/(sqrt(Px^2+Py^2))))-(atan((l3+l4)*sin(-q31)/(l2+((l3+l4)*cos(-q31))))));
    q22 = ((atan((Pz-l1)/(sqrt(Px^2+Py^2))))+(atan((l3+l4)*sin(-q32)/(l2+((l3+l4)*cos(-q32))))));
    q23 = q21 * -1;
    q24 = q22 * -1;

    if q21 > 0
       q21 = q21 - pi;
    end
    if q22 > 0
        q22 = q22 - pi;
    end
    if q23 > 0
        q23 = q23 - pi;
    end
    if q24 > 0
        q24 = q24 - pi;
    end

    MTH01 = eye(4);
    MTH11 = app.denavit(q1,l1,0,-pi/2);
    MTH21 = app.denavit(q21,0,l2,0);
    MTH31 = app.denavit(q31-pi/2,0,0,-pi/2);
    
    MTH011 = MTH01*MTH11;
    MTH021 = MTH011*MTH21;
    MTH031 = MTH021*MTH31;
    
    x31 = [MTH031(1,1),MTH031(1,2),MTH031(1,3),0];
    y31 = [MTH031(2,1),MTH031(2,2),MTH031(2,3),0];
    
    MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q22,0,l2,0);
    MTH32 = app.denavit(q32-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x32 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y32 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];

    MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q21,0,l2,0);
    MTH32 = app.denavit(q32-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x33 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y33 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];

    MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q22,0,l2,0);
    MTH32 = app.denavit(q31-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x34 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y34 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];

    MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q23,0,l2,0);
    MTH32 = app.denavit(q31-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x35 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y35 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];
    
        MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q23,0,l2,0);
    MTH32 = app.denavit(q32-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x36 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y36 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];
    
        MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q24,0,l2,0);
    MTH32 = app.denavit(q31-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x37 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y37 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];
    
        MTH02 = eye(4);
    MTH12 = app.denavit(q1,l1,0,-pi/2);
    MTH22 = app.denavit(q24,0,l2,0);
    MTH32 = app.denavit(q32-pi/2,0,0,-pi/2);
    
    MTH012 = MTH02*MTH12;
    MTH022 = MTH012*MTH22;
    MTH032 = MTH022*MTH32;
    
    x38 = [MTH032(1,1),MTH032(2,1),MTH032(3,1),0];
    y38 = [MTH032(1,2),MTH032(2,2),MTH032(3,2),0];
    
    
    q41 = atan(dot(x4,y31)/dot(x31,x4));
    q42 = atan(dot(x4,y32)/dot(x32,x4));
    q43 = atan(dot(x4,y33)/dot(x33,x4));
    q44 = atan(dot(x4,y34)/dot(x34,x4));
    q45 = atan(dot(x4,y35)/dot(x35,x4));
    q46 = atan(dot(x4,y36)/dot(x36,x4));
    q47 = atan(dot(x4,y37)/dot(x37,x4));
    q48 = atan(dot(x4,y38)/dot(x38,x4));

    q1 = round(rad2deg(q1),2);
    q21 = round(rad2deg(q21),2);
    q22 = round(rad2deg(q22),2);
    q23 = round(rad2deg(q23),2);
    q24 = round(rad2deg(q24),2);
    q31 = round(rad2deg(q31),2);
    q32 = round(rad2deg(q32),2);
    q41 = round(rad2deg(q41),2);
    q42 = round(rad2deg(q42),2);
    q43 = round(rad2deg(q43),2);
    q44 = round(rad2deg(q44),2);
    q45 = round(rad2deg(q45),2);
    q46 = round(rad2deg(q46),2);
    q47 = round(rad2deg(q47),2);
    q48 = round(rad2deg(q48),2);

% q1 == 8
% q2 =! 4
% q3 =! 2
% q4 =! 8


% ---- Crear tabla para mostrar (strings), con 8 soluciones ----
Qtable = ["Q1", string(q1), string(q1), string(q1), string(q1), string(q1), string(q1), string(q1), string(q1);
          "Q2", string(q24), string(q23), string(q23), string(q24), string(q22), string(q22), string(q21), string(q21);
          "Q3", string(q31), string(q32), string(q31), string(q32), string(q31), string(q32), string(q31), string(q32);
          "Q4", string(q48), string(q45), string(q41), string(q47), string(q43), string(q46), string(q44), string(q42)];
app.TablaInver.Data = Qtable;

% ---- Devolver valores numéricos (8 soluciones en filas) ----
Q = [q1 q24 q31 q48;
     q1 q23 q32 q45;
     q1 q23 q31 q41;
     q1 q24 q32 q47;
     q1 q22 q31 q43;
     q1 q22 q32 q46;
     q1 q21 q31 q44;
     q1 q21 q32 q42];


end
    function Q = InversaAnaliticaMTH(app, T)
        % ---- Extraer componentes de T (N O A P) ----
        Ax = T(1,3);
        Ay = T(2,3);
        Az = T(3,3);
        Oz = T(3,2);
        Nz = T(3,1);
        Px = T(1,4);
        Py = T(2,4);
        Pz = T(3,4);
    
        % ---- Parámetros del robot ----
        L1  = 7.6;
        L2  = 10.5;
        L3  = 10;
        L4  = 4;
        L34 = L3 + L4;
    
        % ---- Cálculo de ángulos en radianes ----
        q23 = atan2(-Az, sqrt(1 - (-Az)^2));
        q1  = atan2(Ay, Ax);
        q4  = atan2(-Oz, Nz);
    
        W = cos(q23);
        S = sin(q23);    % uso S para no pisar la T de la matriz
        N = cos(q1);
    
        senq2 = (Pz - L1 + L34 * S) / (-L2);
        cosq2 = (Px - N * W * L34) / (N * L2);
        q2    = atan2(senq2, cosq2);
        q3    = q23 - q2;
    
        % ---- Pasar a grados ----
        q1 = rad2deg(q1);
        q2 = rad2deg(q2);
        q3 = rad2deg(q3);
        q4 = rad2deg(q4);
    
        % ---- Aplicar compensaciones por cuadrantes usando la función de las compensaciones ----
        [q1, q2, q3, q4] = app.compensacionCuadrantes(q1, q2, q3, q4, Px, Py);
        
        % ---- Redondear q4 cuando sea muy cercano a 0 (evitar valores como 0.00032232) ----
        if abs(q4) < 0.01  % Umbral de 0.01 grados
            q4 = 0;
        end
        
        % ---- Asegurar que q4 esté dentro del rango físico [-90, 90] ----
        if q4 > 90
            q4 = 0;
        elseif q4 < -90
            q4 = 0;
        end
    
        % ---- Solución numérica ----
        sols = [q1, q2, q3, q4];
    
        % ---- Tabla para la app ----
        Qtable = [ "Q1", string(sols(1));
                   "Q2", string(sols(2));
                   "Q3", string(sols(3));
                   "Q4", string(sols(4)) ];
    
        app.TablaInver.Data = Qtable;  % mostrar en la UI
    
        Q = sols;   % devolver [Q1 Q2 Q3 Q4] numérico
    end

    function [q1, q2, q3, q4] = compensacionCuadrantes(app, q1, q2, q3, q4, Px, Py)
        % Determinar el cuadrante basado en Px y Py
        if Px >= 0 && Py >= 0
            % 1er cuadrante: sin cambios
            % q1, q2, q3, q4 se mantienen como están
            
        elseif Px < 0 && Py >= 0
            % 2do cuadrante: sin cambios
            
        elseif Px < 0 && Py < 0
            % 3er cuadrante
            q1 = 180 + q1;
            q2 = -180 - q2;
            q3 = -q3;             
            q4 = q4 + 180;
            q4 = mod(q4+180,360)-180;
            if q4> 90, q4=q4-180; 
            end
            if q4<-90, q4=q4+180; 
            end
            
        else
            % 4to cuadrante (Px >= 0 && Py < 0)
            q1 = 180 + q1;
            
            % Ajuste para q2 y q3
            q2 = -180 - q2;
            q3 = -q3;
            
            % Ajuste para q4
            q4 = q4 + 180;
            
            % Normalizar q4 al rango [-180, 180]
            q4 = mod(q4 + 180, 360) - 180;
            
            % Asegurar que q4 esté en rango adecuado para q4
            if q4 > 90
                q4 = q4 - 180;
            elseif q4 < -90
                q4 = q4 + 180;
            end
        end
    end

    function activarButtonPushed(app, event)

        if app.isRunning == false
            puerto=app.SERIALEditField.Value;
            app.arduino = serial(puerto, 'BaudRate', 9600);
            fopen(app.arduino);
            app.isRunning = true;
            app.ComunicacionLamp.Color=[0,1,0];
            
        end
     end
    function desactivarButtonPushed(app, event)
        if app.isRunning
            fclose(app.arduino);
            delete(app.arduino);
            app.isRunning = false;
            app.ComunicacionLamp.Color=[1,0,0];
        end
    end
        
     function enviarvalor(app, event)
        if app.isRunning
            
            q1=app.Q1E.Value;
            q2=app.Q2E.Value;
            q3=app.Q3E.Value;
            q4=app.Q4E.Value;
            el=app.EFCE.Value;
            
            
            %q=[q1,q2,q3,q4];
            % 
            % V1=0+q(1);
            % V2=0+q(2);
            % V3=0-q(3);
            % V4=0+q(4);
            % Q1=[V1,V2,V3,V4];
            % Convertir los valores en cadenas y concatenarlos


            valor = [num2str(q1), ',', num2str(q2), ',', num2str(q3), ',', num2str(q4), ',', num2str(el)];
            if ~isnan(valor)
                trama = num2str(valor);
                fwrite(app.arduino, trama, 'uint8');
            end
        end
     end
        
    function mejor_sol = seleccionarMejorSolucion(app, Qsol, T_deseada)
        % T_deseada: matriz NOAP original de la cinemática directa
        limites = [-180 180; -180 0; -90 90; -90 90];
        mejor_sol = [];
        menor_error = inf;
        
        % Obtener valores actuales de los campos de entrada
        q1_actual = app.Q1E.Value;
        q2_actual = app.Q2E.Value;
        q3_actual = app.Q3E.Value;
        q4_actual = app.Q4E.Value;
        pos_actual = [q1_actual, q2_actual, q3_actual, q4_actual];
        
        for i = 1:size(Qsol, 1)
            sol = Qsol(i, :);
            
            % Verificar límites
            dentro_limites = true;
            for j = 1:4
                if sol(j) < limites(j,1) || sol(j) > limites(j,2)
                    dentro_limites = false;
                    break;
                end
            end
            
            if dentro_limites
                % Calcular matriz NOAP con Denavit-Hartenberg
                T_calculada = app.DirectaDH(sol(1), sol(2), sol(3), sol(4));
                
                % Calcular error con la NOAP deseada (norma de la diferencia)
                error_NOAP = norm(T_deseada(1:3,1:4) - T_calculada(1:3,1:4));
                
                % Calcular diferencia angular con posición actual
                diferencia_angular = sum(abs(sol - pos_actual));
                diferencia_q2q3 = abs(sol(2) - pos_actual(2)) + abs(sol(3) - pos_actual(3));
                
                % Error total (prioridad: NOAP, luego ángulos)
                error_total = error_NOAP * 1000 + diferencia_angular + diferencia_q2q3 * 2;
                
                if error_total < menor_error
                    menor_error = error_total;
                    mejor_sol = sol;
                end
            end
        end
        
        if isempty(mejor_sol)
            mejor_sol = Qsol(1, :);
        end
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.tabla1.Data = zeros(4, 4);
            app.TablaValInver.Data = zeros(4, 4);
            app.TablaInver.Data = ["Q1",0,0,0,0,0,0,0,0;
                                   "Q2",0,0,0,0,0,0,0,0;
                                   "Q3",0,0,0,0,0,0,0,0;
                                   "Q4",0,0,0,0,0,0,0,0];
            app.TablaInverSolOptima.Data = ["Q1",0;
                                            "Q2",0;
                                            "Q3",0;
                                            "Q4",0];
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
           selectedButton = app.SeleccioneMetodoButtonGroup.SelectedObject;
           cla(app.axes);

            Q1=app.Q1E.Value;
            Q2=app.Q2E.Value;
            Q3=app.Q3E.Value;
            Q4=app.Q4E.Value;
           
            if selectedButton == app.DenavitButton
                T = app.DirectaDH(Q1,Q2,Q3,Q4);
            elseif selectedButton == app.GeometricaButton
                T = app.DirectaGeometrica(Q1,Q2,Q3,Q4);
            end
            
            % Actualizar AMBAS tablas
            app.tabla1.Data = T;
            app.TablaValInver.Data = T;
            
            % Guardar la NOAP original para futuras comparaciones
            app.NOAP_original = T;
        end

        % Button pushed function: AnimarButton
        function AnimarButtonPushed(app, event)
            selectedButton = app.SeleccioneMetodoButtonGroup.SelectedObject;
            app.enviarvalor

            Q1=app.Q1E.Value;
            Q2=app.Q2E.Value;
            Q3=app.Q3E.Value;
            Q4=app.Q4E.Value;
           
            if selectedButton == app.DenavitButton
                if app.CambiarMovimientoCheckBox.Value == true
                    app.AnimarConActualizacionTabla1(Q1, Q2, Q3, Q4)
                else
                    app.AnimarPasoPasoConActualizacionTabla1(Q1, Q2, Q3, Q4)
                end
                    
            elseif selectedButton == app.GeometricaButton
                if app.CambiarMovimientoCheckBox.Value == true
                    app.AnimarGeometricaConActualizacionTabla1(Q1, Q2, Q3, Q4)
                else
                    app.AnimarPasoPasoGeometricaConActualizacionTabla1(Q1, Q2, Q3, Q4)
                end
            end
            
        end

        % Button pushed function: ActivarComunicacion
        function ActivarComunicacionButtonPushed(app, event)
            if app.Banderita == false
                app.Banderita=true;
                app.ActivarComunicacion.Text="Desactivar";
                app.activarButtonPushed
            else
                app.Banderita=false;
                app.ActivarComunicacion.Text="Activar";
                app.desactivarButtonPushed
            end
        end

        % Button pushed function: EjecutarButton_2
        function EjecutarButton_2Pushed(app, event)
            selectedButton = app.SeleccioneMetodoInvButtonGroup.SelectedObject;


            if selectedButton == app.AnaliticaButton
                
                T = app.TablaValInver.Data;
                Qsol = app.InversaAnaliticaMTH(T);
                
                if ~isempty(Qsol)
                % Calcular NOAP con DH para la solución analítica
                T_calculada = app.DirectaDH(Qsol(1), Qsol(2), Qsol(3), Qsol(4));
                app.TablaValInver.Data = T_calculada;  % Mostrar la NOAP resultante
                
                app.TablaInverSolOptima.Data = ["Q1","";
                                                "Q2","";
                                                "Q3","";
                                                "Q4",""];
               
                
                    app.Animar(Qsol(1),Qsol(2),Qsol(3),Qsol(4));
                end
        
            elseif selectedButton == app.GeometricaInvButton
                
                % Guardar la NOAP original ANTES de que se modifique
                T_original = app.TablaValInver.Data;

                % Obtener todas las soluciones
                Qsol = app.InversaGeo();  
                
                
                if ~isempty(Qsol)
                    % Seleccionar mejor solución comparando con NOAP original
                    mejor_sol = app.seleccionarMejorSolucion(Qsol, T_original);
                    app.qq_actual = mejor_sol;
                    
                    % Calcular NOAP con DH para la solución seleccionada
                    T_resultante = app.DirectaDH(mejor_sol(1), mejor_sol(2), mejor_sol(3), mejor_sol(4));
                    
                    % Actualizar tablas con la NOAP resultante
                    app.TablaValInver.Data = T_resultante;
                    
                    % Mostrar solución óptima
                    tablaOptima = ["Q1", string(mejor_sol(1));
                               "Q2", string(mejor_sol(2));
                               "Q3", string(mejor_sol(3));
                               "Q4", string(mejor_sol(4))];
                    app.TablaInverSolOptima.Data = tablaOptima;
                    
                    app.Animar(mejor_sol(1), mejor_sol(2), mejor_sol(3), mejor_sol(4));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.tabla1 = uitable(app.UIFigure);
            app.tabla1.ColumnName = {'N'; 'O'; 'A'; 'P'};
            app.tabla1.RowName = {};
            app.AnimarButton = uibutton(app.UIFigure, 'push');
            app.AnimarButton.ButtonPushedFcn = createCallbackFcn(app, @AnimarButtonPushed, true);
            app.AnimarButton.Text = 'Animar';
            app.CambiarMovimientoCheckBox = uicheckbox(app.UIFigure);
            app.CambiarMovimientoCheckBox.Text = 'Cambiar Movimiento';
            app.ActivarComunicacion = uibutton(app.UIFigure, 'push');
            app.ActivarComunicacion.ButtonPushedFcn = createCallbackFcn(app, @ActivarComunicacionButtonPushed, true);
            app.ActivarComunicacion.Text = 'ACTIVAR';
            app.SeleccioneMetodoButtonGroup = uibuttongroup(app.UIFigure);
            app.SeleccioneMetodoButtonGroup.Title = 'Seleccione Metodo ';
            app.GeometricaButton = uiradiobutton(app.SeleccioneMetodoButtonGroup);
            app.GeometricaButton.Text = 'Geometrica';
            app.GeometricaButton.Value = true;
            app.DenavitButton = uiradiobutton(app.SeleccioneMetodoButtonGroup);
            app.DenavitButton.Text = 'Denavit-Hartenberg';
            app.a180Label = uilabel(app.UIFigure);
            app.a180Label.Text = '0 a 180';
            app.a0Label = uilabel(app.UIFigure);
            app.a0Label.Text = '-180 a 0';
            app.a90Label = uilabel(app.UIFigure);
            app.a90Label.Text = '-90 a 90';
            app.a90Label_2 = uilabel(app.UIFigure);
            app.a90Label_2.Text = '-90 a 90';
            app.ComunicacionLamp = uilamp(app.UIFigure);
            app.Q1E = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2E = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3E = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4E = uieditfield(app.UIFigure, 'numeric');
            app.SERIALEditFieldLabel = uilabel(app.UIFigure);
            app.SERIALEditFieldLabel.Text = 'SERIAL';
            app.SERIALEditField = uieditfield(app.UIFigure, 'text');
            app.SERIALEditField.Value = 'COM';
            app.Label = uilabel(app.UIFigure);
            app.CinematicaDirectaLabel = uilabel(app.UIFigure);
            app.CinematicaDirectaLabel.Text = 'Cinematica Directa';
            app.CinematicaInversaLabel = uilabel(app.UIFigure);
            app.CinematicaInversaLabel.Text = 'Cinematica Inversa';
            app.ValoresArticularesLabel = uilabel(app.UIFigure);
            app.ValoresArticularesLabel.Text = 'Valores Articulares';
            app.SeleccioneMetodoInvButtonGroup = uibuttongroup(app.UIFigure);
            app.SeleccioneMetodoInvButtonGroup.Title = 'Seleccione Metodo';
            app.GeometricaInvButton = uiradiobutton(app.SeleccioneMetodoInvButtonGroup);
            app.GeometricaInvButton.Text = 'Geometrica';
            app.GeometricaInvButton.Value = true;
            app.AnaliticaButton = uiradiobutton(app.SeleccioneMetodoInvButtonGroup);
            app.AnaliticaButton.Text = 'Analitica';
            app.EjecutarButton_2 = uibutton(app.UIFigure, 'push');
            app.EjecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButton_2Pushed, true);
            app.EjecutarButton_2.Text = 'Ejecutar';
            app.MTHTotalLabel = uilabel(app.UIFigure);
            app.MTHTotalLabel.Text = 'MTH Total';
            app.TablaInver = uitable(app.UIFigure);
            app.TablaInver.ColumnName = {'Q'; 'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'S6'; 'S7'; 'S8'};
            app.TablaInver.RowName = {};
            app.SolucionesdelaCinematicaInversaLabel = uilabel(app.UIFigure);
            app.SolucionesdelaCinematicaInversaLabel.Text = 'Soluciones de la Cinematica Inversa';
            app.TablaValInver = uitable(app.UIFigure);
            app.TablaValInver.ColumnName = {'N'; 'O'; 'A'; 'P'};
            app.TablaValInver.RowName = {};
            app.TablaValInver.ColumnEditable = true;
            app.ValoresDeLaInversaLabel = uilabel(app.UIFigure);
            app.ValoresDeLaInversaLabel.Text = 'Valores De La Inversa';
            app.EFCEditFieldLabel = uilabel(app.UIFigure);
            app.EFCEditFieldLabel.Text = 'EFC';
            app.EFCE = uieditfield(app.UIFigure, 'text');
            app.EFCE.Value = '0';
            app.SolucionOptimaLabel = uilabel(app.UIFigure);
            app.SolucionOptimaLabel.Text = {'Solucion'; 'Optima'};
            app.TablaInverSolOptima = uitable(app.UIFigure);
            app.TablaInverSolOptima.ColumnName = {'Q'; 'S.O'};
            app.TablaInverSolOptima.RowName = {};
            app.AR4DFLabel = uilabel(app.UIFigure);
            app.AR4DFLabel.Text = 'AR – 4DF';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Manipulador')
            xlabel(app.axes, 'X[cm]')
            ylabel(app.axes, 'Y[cm]')
            zlabel(app.axes, 'Z[cm]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_prev_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end